classdef GUI_general_double_pen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        clearmotionButton    matlab.ui.control.Button
        plotmotionButton     matlab.ui.control.Button
        letL2L1Button        matlab.ui.control.Button
        letm2m1Button        matlab.ui.control.Button
        goButton             matlab.ui.control.Button
        phi0piSlider         matlab.ui.control.Slider
        phi0piSliderLabel    matlab.ui.control.Label
        theta0piSlider       matlab.ui.control.Slider
        theta0piSliderLabel  matlab.ui.control.Label
        gSlider              matlab.ui.control.Slider
        gSliderLabel         matlab.ui.control.Label
        L2Slider             matlab.ui.control.Slider
        L2SliderLabel        matlab.ui.control.Label
        L1Slider             matlab.ui.control.Slider
        L1SliderLabel        matlab.ui.control.Label
        m2Slider             matlab.ui.control.Slider
        m2SliderLabel        matlab.ui.control.Label
        m1Slider             matlab.ui.control.Slider
        m1SliderLabel        matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        UIAxes4              matlab.ui.control.UIAxes
        UIAxes3              matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        UIAxes               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: goButton
        function goButtonPushed(app, event)
            %try
                cla(app.UIAxes);
                cla(app.UIAxes2);
                cla(app.UIAxes3);
                m = 0;
                l = 0;
                m(1) = app.m1Slider.Value;
                m(2) = app.m2Slider.Value;
                l(1) = app.L1Slider.Value;
                l(2) = app.L2Slider.Value;
                g = app.gSlider.Value;
                theta0_nopi = app.theta0piSlider.Value;
                phi0_nopi = app.phi0piSlider.Value;
                for j = 1:2
                    loop;
                    cla(app.UIAxes);
                end
                close all;
                clear;
            %catch oException
            %    msgbox(oException.message)
            %end

        end

        % Button pushed function: letm2m1Button
        function letm2m1ButtonPushed(app, event)
            m(1) = app.m1Slider.Value;
            m(2) = app.m1Slider.Value;
            app.m2Slider.Value = app.m1Slider.Value;
        end

        % Button pushed function: letL2L1Button
        function letL2L1ButtonPushed(app, event)
            l(1) = app.L1Slider.Value;
            l(2) = app.L1Slider.Value;
            app.L2Slider.Value = app.L1Slider.Value;
        end

        % Button pushed function: plotmotionButton
        function plotmotionButtonPushed(app, event)
            tspan = 0:0.1:100;
            m(1) = app.m1Slider.Value;
            m(2) = app.m2Slider.Value;
            l(1) = app.L1Slider.Value;
            l(2) = app.L2Slider.Value;
            g = app.gSlider.Value;
            theta0_nopi = app.theta0piSlider.Value;
            phi0_nopi = app.phi0piSlider.Value;
            y0 = [theta0_nopi*pi;0; phi0_nopi*pi; 0];
            options=odeset('RelTol',1e-7);
            [tout,yout]=ode45(@(t,y) double_pen(t,y,m,l,g) , tspan,y0,options);
            mox = l(1)*sin(yout(1:500,1)) + l(2)*sin(yout(1:500,3));
            moy = 0-l(1)*cos(yout(1:500,1)) - l(2)*cos(yout(1:500,3));
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,mox,moy);
            axis(app.UIAxes4,[-20 20 -20 20],'square');
        end

        % Button pushed function: clearmotionButton
        function clearmotionButtonPushed(app, event)
            cla(app.UIAxes4);
        end

        % Callback function
        function StopButtonPushed(app, event)
  
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {506, 506};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {201, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.m1SliderLabel = uilabel(app.LeftPanel);
            app.m1SliderLabel.Text = 'm1 = ';
            app.m1Slider = uislider(app.LeftPanel);
            app.m1Slider.Limits = [0 50];
            app.m1Slider.Value = 10;
            app.m2SliderLabel = uilabel(app.LeftPanel);
            app.m2SliderLabel.Text = 'm2 = ';
            app.m2Slider = uislider(app.LeftPanel);
            app.m2Slider.Limits = [0 50];
            app.m2Slider.Value = 10;
            app.L1SliderLabel = uilabel(app.LeftPanel);
            app.L1SliderLabel.Text = 'L1 = ';
            app.L1Slider = uislider(app.LeftPanel);
            app.L1Slider.Limits = [0 10];
            app.L1Slider.Value = 7;
            app.L2SliderLabel = uilabel(app.LeftPanel);
            app.L2SliderLabel.Text = 'L2 = ';
            app.L2Slider = uislider(app.LeftPanel);
            app.L2Slider.Limits = [0 10];
            app.L2Slider.Value = 7;
            app.gSliderLabel = uilabel(app.LeftPanel);
            app.gSliderLabel.Text = 'g = ';
            app.gSlider = uislider(app.LeftPanel);
            app.gSlider.Limits = [0 20];
            app.gSlider.Value = 10;
            app.theta0piSliderLabel = uilabel(app.LeftPanel);
            app.theta0piSliderLabel.Text = 'theta0/pi = ';
            app.theta0piSlider = uislider(app.LeftPanel);
            app.theta0piSlider.Limits = [-1 1];
            app.theta0piSlider.Value = 0.5;
            app.phi0piSliderLabel = uilabel(app.LeftPanel);
            app.phi0piSliderLabel.Text = 'phi0/pi = ';
            app.phi0piSlider = uislider(app.LeftPanel);
            app.phi0piSlider.Limits = [-1 1];
            app.phi0piSlider.Value = -0.5;
            app.goButton = uibutton(app.LeftPanel, 'push');
            app.goButton.ButtonPushedFcn = createCallbackFcn(app, @goButtonPushed, true);
            app.goButton.Text = 'go';
            app.letm2m1Button = uibutton(app.LeftPanel, 'push');
            app.letm2m1Button.ButtonPushedFcn = createCallbackFcn(app, @letm2m1ButtonPushed, true);
            app.letm2m1Button.Text = 'let m2 = m1';
            app.letL2L1Button = uibutton(app.LeftPanel, 'push');
            app.letL2L1Button.ButtonPushedFcn = createCallbackFcn(app, @letL2L1ButtonPushed, true);
            app.letL2L1Button.Text = 'let L2 = L1';
            app.plotmotionButton = uibutton(app.LeftPanel, 'push');
            app.plotmotionButton.ButtonPushedFcn = createCallbackFcn(app, @plotmotionButtonPushed, true);
            app.plotmotionButton.Text = 'plot motion';
            app.clearmotionButton = uibutton(app.LeftPanel, 'push');
            app.clearmotionButton.ButtonPushedFcn = createCallbackFcn(app, @clearmotionButtonPushed, true);
            app.clearmotionButton.Text = 'clear motion';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'simulation of pendulum')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '\theta(t) and \phi(t)')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, '\theta    \phi')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, '\theta(\phi)')
            xlabel(app.UIAxes3, '\phi')
            ylabel(app.UIAxes3, '\theta')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'motion of B')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_general_double_pen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end